classdef tankApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DeneyZamannGirerekTab          matlab.ui.container.Tab
        DeneyTarihiLabel               matlab.ui.control.Label
        DeneySaatiLabel                matlab.ui.control.Label
        EditFieldTarih                 matlab.ui.control.EditField
        EditFieldSaat                  matlab.ui.control.EditField
        DeneyTrSeerekTab               matlab.ui.container.Tab
        TurLPMPanel                    matlab.ui.container.ButtonGroup
        TurLPM40Radio                  matlab.ui.control.RadioButton
        TurLPM80Radio                  matlab.ui.control.RadioButton
        TurLPM120Radio                 matlab.ui.control.RadioButton
        TurCapPanel                    matlab.ui.container.ButtonGroup
        TurCap6mmRadio                 matlab.ui.control.RadioButton
        TurCap8mmRadio                 matlab.ui.control.RadioButton
        TurCap10mmRadio                matlab.ui.control.RadioButton
        TurCap12mmRadio                matlab.ui.control.RadioButton
        TurDelikPanel                  matlab.ui.container.Panel
        TurDelik1CheckBox              matlab.ui.control.CheckBox
        TurDelik2CheckBox              matlab.ui.control.CheckBox
        TurDelik4CheckBox              matlab.ui.control.CheckBox
        TurDelik3CheckBox              matlab.ui.control.CheckBox
        TurDelik7CheckBox              matlab.ui.control.CheckBox
        TurDelik6CheckBox              matlab.ui.control.CheckBox
        TurDelik5CheckBox              matlab.ui.control.CheckBox
        TurIkiliDelikPanel             matlab.ui.container.Panel
        TurCokluDelik12CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik13CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik15CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik14CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik17CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik16CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik24CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik23CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik34CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik27CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik26CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik25CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik45CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik37CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik36CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik35CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik57CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik56CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik47CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik46CheckBox        matlab.ui.control.CheckBox
        TurCokluDelik67CheckBox        matlab.ui.control.CheckBox
        TurUcluDelikPanel              matlab.ui.container.Panel
        TurCokluDelik34CheckBox_2      matlab.ui.control.CheckBox
        TurCokluDelik27CheckBox_2      matlab.ui.control.CheckBox
        TurCokluDelik26CheckBox_2      matlab.ui.control.CheckBox
        TurCokluDelik25CheckBox_2      matlab.ui.control.CheckBox
        TurCokluDelik25CheckBox_3      matlab.ui.control.CheckBox
        TurCokluDelik26CheckBox_3      matlab.ui.control.CheckBox
        TurCokluDelik27CheckBox_3      matlab.ui.control.CheckBox
        TurCokluDelik34CheckBox_3      matlab.ui.control.CheckBox
        TurCokluDelik25CheckBox_4      matlab.ui.control.CheckBox
        TurCokluDelik26CheckBox_4      matlab.ui.control.CheckBox
        TurCokluDelik27CheckBox_4      matlab.ui.control.CheckBox
        TurCokluDelik34CheckBox_4      matlab.ui.control.CheckBox
        TurCokluDelik345CheckBox       matlab.ui.control.CheckBox
        TurCokluDelik237CheckBox       matlab.ui.control.CheckBox
        TurCokluDelik267CheckBox       matlab.ui.control.CheckBox
        TurCokluDelik257CheckBox       matlab.ui.control.CheckBox
        ExcelSatrAralnBelirleyerekTab  matlab.ui.container.Tab
        ExcelSatrBalangcLabel          matlab.ui.control.Label
        ExcelSatrSonuLabel             matlab.ui.control.Label
        EditFieldBitis                 matlab.ui.control.NumericEditField
        EditFieldBaslangic             matlab.ui.control.NumericEditField
        EditFieldDosyaIsmi             matlab.ui.control.EditField
        ExcelDosyasnnismiLabel         matlab.ui.control.Label
        xlsxLabel                      matlab.ui.control.Label
        oncekiGrafikCheckBox           matlab.ui.control.CheckBox
        Button                         matlab.ui.control.Button
        VeriPanel                      matlab.ui.container.Panel
        VeriDebiCheckBox               matlab.ui.control.CheckBox
        VeriBasinc2CheckBox            matlab.ui.control.CheckBox
        VeriBasinc1CheckBox            matlab.ui.control.CheckBox
        DeerGstericiPanel              matlab.ui.container.Panel
        VeriSetiDropDown               matlab.ui.control.DropDown
        SaniyeEditFieldLabel           matlab.ui.control.Label
        SaniyeEditField                matlab.ui.control.NumericEditField
        VeriyiGsterButton              matlab.ui.control.Button
        ListBox                        matlab.ui.control.ListBox
        StandartSapmalarLabel          matlab.ui.control.Label
        TurBogazPanel                  matlab.ui.container.ButtonGroup
        BogazliRadio                   matlab.ui.control.RadioButton
        BogazsizRadio                  matlab.ui.control.RadioButton
        BasncagrekalibreetCheckBox     matlab.ui.control.CheckBox
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        startOfDatalogger = 0; % when data logger is opened
        
        startOfFilling = 0; % when filling tank is started
        
        finishOfFilling = 0; % when filling tank is finished
        
        time = string([]); % clock values of row array of datalogger
       
        timeToSave = string([]);
        clock = string([]);
        
        flowmeter = []; % flowmeter values of row array of datalogger
        
        pressure = [];

        titleValue = ''; % graph title
        
        legendValue = string([]); % legend array for graph
        
        tank_1 = []; % all tank sensor values in specific row range (flowmeter and pressures sensors)
        
        t; % time array used in graph
        
        bogazBagli;
        
        plots = gobjects;

        dataset = string([]);
        
        standard_deviation = string([]);

        delikler = string([]);
        ikiliDelikler = string([]);
        ucluDelikler = string([]); 
        
        rgbIndex = 0;
        rgb = {[1 0.843 0.706], [0.902 0.098 0.294], [0 0.51 0.784], [0.235 0.706 0.294], [0.001 0.001 0.001], [0.569 0.118 0.706], [0.999 0.882 0.098], [0.961 0.51 0.188], [0.275 0.941 0.941], [0.502 0.502 0.502], [0.502 0 0], [0.941 0.196 0.902], [0.667 0.431 0.157], [0 0 0.491], [0.491 0.491 0], [0.98 0.745 0.831], [0.863 0.745 1], [0.824 0.961 0.235], [1 0.98 0.784], [0 0.491 0.491], [0.667 1 0.765]};
        rows = ["C2", "D2", "E2", "F2", "G2", "H2"];
                
    end
    
    methods (Access = private)
        
        function variables(app, tarih)
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %preparing needed variables
                [sizeOfRow, ~] = size(xlsread(append(tarih, '.xlsx'), 'Sayfa1', 'D:D'));
                %sizeOfColumn: column number/~: Row number
                %D:D -> Reading all D column
                
                %to read all clock in clock time column (B column)
                [~,app.time] = xlsread(append(tarih, '.xlsx'), 'Sayfa1', append('B2:B', string(sizeOfRow)));
                %[~,time] -> to read column as string
                
                %to read all flowmeter values in flowmeter column (D column)
                app.flowmeter = xlsread(append(tarih, '.xlsx'), 'Sayfa1', append('D2:D', string(sizeOfRow)));
                
                app.pressure = xlsread(append(tarih, '.xlsx'), 'Sayfa1', append('F2:F', string(sizeOfRow)));
                
                    
        end
        
        function rowsValue(app, saat)
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %to find row number of when data logger is opened
                for i = 1:length(app.time)
                    if strcmp(app.time{i}, saat)
                        app.startOfDatalogger = i;
                        break
                    end
                end

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %to find row number of when filling tank is started
                for k = app.startOfDatalogger:length(app.time)
                    if strcmp(app.TabGroup.SelectedTab.Title, 'Deney Türü Seçerek')
                        if ((~app.bogazBagli && app.flowmeter(k) > 1) || (app.bogazBagli && app.pressure(k) > 1.001))
                            app.startOfFilling = k - 20;
                            break
                        end
                    else
                        if strcmp(app.TurBogazPanel.SelectedObject.Text, 'Boğaza Bağlı')
                            if app.pressure(k) > 1.001
                                app.startOfFilling = k - 20;
                                break
                            end
                        else
                            if (~app.BasncagrekalibreetCheckBox.Value && app.flowmeter(k) > 1) || (app.BasncagrekalibreetCheckBox.Value && app.pressure(k) > 1.001)
                                app.startOfFilling = k - 20;
                                break
                            end
                        end
                    end
                end

                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %to find row number of when filling tank is finished
                for j = app.startOfFilling + 70:length(app.time)
                    a  = true;
                    for k=1:110

                            if(length(app.flowmeter)>j + 2*k)
                                if strcmp(app.TabGroup.SelectedTab.Title, 'Deney Türü Seçerek')
                                    if ((~app.BasncagrekalibreetCheckBox.Value && ~app.bogazBagli && app.flowmeter(j + 2*k) > 0.55) || (app.BasncagrekalibreetCheckBox.Value && ~app.bogazBagli && app.pressure(j + 2*k) > 1.001) || (app.bogazBagli && app.pressure(j + 2*k) > 1.001))
                                        a = false;
                                    end
                                else
                                    if (~app.BasncagrekalibreetCheckBox.Value && app.flowmeter(j + 2*k) > 0.55 ) || (app.BasncagrekalibreetCheckBox.Value && app.pressure(j + 2*k) > 1.001)
                                       a = false;
                                    end
                                end
                            else
                                break
                            end
                    end
                    if a
                       app.finishOfFilling = j;
                       break
                    end
                end 

        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % getting X and Y array for plotting graph
        function initializingGraph(app, dosyaIsmi, baslangic, bitis, identity)
            if strcmp(app.TabGroup.SelectedTab.Title, 'Deney Türü Seçerek')
                if isfile('Deney_Verileri.xlsx')
                    if ismember(strrep(strrep(strrep(identity,' ',''), 'diameter', 'd'), 'hole', 'h'), sheetnames('Deney_Verileri.xlsx'))
                         [sizeOfRow, ~] = size(xlsread('Deney_Verileri.xlsx', strrep(strrep(strrep(identity,' ',''), 'diameter', 'd'), 'hole', 'h'), 'D:D'));
                         app.tank_1 = xlsread('Deney_Verileri.xlsx', strrep(strrep(strrep(identity,' ',''), 'diameter', 'd'), 'hole', 'h'), append('C2:H', string(sizeOfRow)));
                         app.t=0.05:0.05:(sizeOfRow - 1)*0.05;
                    else
                         app.tank_1 = xlsread(append(dosyaIsmi, '.xlsx'), 'Sayfa1', append('C', string(baslangic),':H', string(bitis)));   
                         [~, app.timeToSave] = xlsread(append(dosyaIsmi, '.xlsx'), 'Sayfa1', append('A', string(baslangic),':A', string(bitis)));
                         [~, app.clock] = xlsread(append(dosyaIsmi, '.xlsx'), 'Sayfa1', append('B', string(baslangic),':B', string(bitis))); 
                         app.t=0.05:0.05:(bitis-baslangic + 1)*0.05;
                    end
                else
                     app.tank_1 = xlsread(append(dosyaIsmi, '.xlsx'), 'Sayfa1', append('C', string(baslangic),':H', string(bitis)));   
                     [~, app.timeToSave] = xlsread(append(dosyaIsmi, '.xlsx'), 'Sayfa1', append('A', string(baslangic),':A', string(bitis)));
                     [~, app.clock] = xlsread(append(dosyaIsmi, '.xlsx'), 'Sayfa1', append('B', string(baslangic),':B', string(bitis))); 
                     app.t=0.05:0.05:(bitis-baslangic + 1)*0.05;  
                end
            else
                app.tank_1 = xlsread(append(dosyaIsmi, '.xlsx'), 'Sayfa1', append('C', string(baslangic),':H', string(bitis)));   
                app.t=0.05:0.05:(bitis-baslangic + 1)*0.05;
            end

        end 
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % plotting pressure 1
        function plottingBasinc1(app, deney)
            if(app.VeriBasinc1CheckBox.Value)
                app.rgbIndex = app.rgbIndex + 1;
                hold(app.UIAxes,'on');
                app.titleValue = 'Basınç 1';
                app.legendValue(length(app.legendValue)+1) = append(string(length(app.legendValue)+1), '. ', deney, 'Basınç 1');
                app.plots(length(app.plots)+1) = plot(app.UIAxes, app.t, app.tank_1(:, 3), 'Color', app.rgb{mod(app.rgbIndex, 20) + 1}, 'LineWidth', 1.5);
                if strcmp(app.TabGroup.SelectedTab.Title, 'Deney Türü Seçerek')
                    app.dataset(length(app.dataset)+1) = string(jsonencode(app.tank_1(:, 3)));
                    stdev(app, app.tank_1(:, 3));
                end
            end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % plotting pressure 2
        function plottingBasinc2(app, deney)
              if(app.VeriBasinc2CheckBox.Value)
                    app.rgbIndex = app.rgbIndex + 1;
                    hold(app.UIAxes,'on');
                    if(strcmp(app.titleValue, 'Basınç 1'))
                        app.titleValue = append(app.titleValue, ', Basınç 2');
                    else
                        app.titleValue = 'Basınç 2';
                    end
                    app.legendValue(length(app.legendValue)+1) = append(string(length(app.legendValue)+1), '. ', deney, 'Basınç 2');
                    app.plots(length(app.plots)+1) = plot(app.UIAxes, app.t, app.tank_1(:, 4), 'Color' , app.rgb{mod(app.rgbIndex, 20) + 1},'LineWidth', 1.5);
                    if strcmp(app.TabGroup.SelectedTab.Title, 'Deney Türü Seçerek')
                        app.dataset(length(app.dataset)+1) = string(jsonencode(app.tank_1(:, 4)));
                        stdev(app, app.tank_1(:, 4));
                    end
              end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % plotting debi
        function plottingDebi(app, deney)
                if(app.VeriDebiCheckBox.Value)
                    app.rgbIndex = app.rgbIndex + 1;
                    hold(app.UIAxes,'on');
                    if(contains(app.titleValue, 'Basınç'))
                        app.titleValue = append(app.titleValue, ', Debi');
                    else
                        app.titleValue = 'Debi';
                    end
                    app.legendValue(length(app.legendValue)+1) = append(string(length(app.legendValue)+1), '. ', deney, 'Debi');
                    app.plots(length(app.plots)+1) = plot(app.UIAxes, app.t, app.tank_1(:, 2), 'Color' , app.rgb{mod(app.rgbIndex, 20) + 1},'LineWidth', 1.5);
                    if strcmp(app.TabGroup.SelectedTab.Title, 'Deney Türü Seçerek')
                        app.dataset(length(app.dataset)+1) = string(jsonencode(app.tank_1(:, 2)));
                        stdev(app, app.tank_1(:, 2))
                    end
                end

        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % plotting labelling graph
        function graphLabels(app)
            title(app.UIAxes, app.titleValue);
            app.VeriSetiDropDown.Items = app.legendValue;
            xlabel(app.UIAxes, 'Zaman (saniye)');
            ylabel(app.UIAxes, app.titleValue);
            legend(app.UIAxes, app.legendValue, 'Location','northeastoutside');
            app.ListBox.Items = app.standard_deviation;
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % resetting global variable for repeating
        function resettingGlobalVariables(app)             
        app.startOfFilling = 0; % when filling tank is started   
        app.finishOfFilling = 0; % when filling tank is finished
        app.time = string([]);       
        app.flowmeter = [];
        app.pressure = [];
        app.titleValue = ''; % Description  
        app.tank_1 = []; % Description
        app.delikler = string([]);
        app.ikiliDelikler = string([]);
        app.ucluDelikler = string([]); 
        end
        
        function stdev(app, data_basinc)
            sum = 0;
            for i = 200:(length(data_basinc) - 60)
                sum = sum + data_basinc(i);
            end
            
            mean = sum/(length(data_basinc)-260);
            sum_squared = 0;
            for j = 200:(length(data_basinc)-60)
                sum_squared = sum_squared + (data_basinc(j) - mean).^2;
            end
            app.standard_deviation(length(app.standard_deviation)+1) = append(string(length(app.standard_deviation)+1), '------   ', string(sqrt(sum_squared/length(data_basinc))));
        end
        
        function resettingGraph(app)
            cla(app.UIAxes);
            app.plots = gobjects;
            app.dataset = string([]);
            app.standard_deviation = string([]);
            app.legendValue = string([]);
            app.rgbIndex = 0;
        end
        
        function saveAsExcel(app, index, row, identity)
            inputData = app.tank_1(:, index);
            excel_Writable_Data = {};
            for i = 1:size(inputData,1)
                excel_Writable_Data = [excel_Writable_Data num2cell(inputData(i,1))];
            end
            writecell(excel_Writable_Data','Deney_Verileri.xlsx','Sheet',strrep(strrep(strrep(identity,' ',''), 'diameter', 'd'), 'hole', 'h'), 'Range', row);
            writecell(app.timeToSave,'Deney_Verileri.xlsx','Sheet',strrep(strrep(strrep(identity,' ',''), 'diameter', 'd'), 'hole', 'h'),'Range','A2');
            writecell(app.clock,'Deney_Verileri.xlsx','Sheet',strrep(strrep(strrep(identity,' ',''), 'diameter', 'd'), 'hole', 'h'),'Range','B2');
            writematrix(["Tarih (Gün.Ay.Yıl)", "Saat (Saat:Dakika:Saniye)", "Flowmetre Voltajı (Volt)", "Flowmetre Değeri (LPM)", "Basınç 1 (Bar)", "Basınç 2 (Bar)", "Basınç 1 Voltajı (Volt)", "Basınç 2 Voltajı (Volt)"],'Deney_Verileri.xlsx','Sheet',strrep(strrep(strrep(identity,' ',''), 'diameter', 'd'), 'hole', 'h'),'Range','A1');
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            location = uigetdir('', 'Excellerin Olduğu Klasörü Seçiniz');
            while ~location
                location = uigetdir('', 'Excellerin Olduğu Klasörü Seçiniz');
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if(app.VeriDebiCheckBox.Value || app.VeriBasinc1CheckBox.Value || app.VeriBasinc2CheckBox.Value)  %checking whether any data checkbox is selected or not       
                
                switch app.TabGroup.SelectedTab.Title
                    case 'Deney Türü Seçerek'
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %preparing variables
                        app.bogazBagli = strcmp(app.TurBogazPanel.SelectedObject.Text, 'Boğaza Bağlı');
                        resettingGlobalVariables(app);
                        dim = split(app.TurCapPanel.SelectedObject.Text);
                        bogaz = '';
                        
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %initializing interface and data array
                        checkboxArray = {app.TurDelik1CheckBox.Value, app.TurDelik2CheckBox.Value, app.TurDelik3CheckBox.Value, app.TurDelik4CheckBox.Value, app.TurDelik5CheckBox.Value, app.TurDelik6CheckBox.Value, app.TurDelik7CheckBox.Value};
                        cokluCheckboxArray = {app.TurCokluDelik12CheckBox.Value, app.TurCokluDelik13CheckBox.Value, app.TurCokluDelik14CheckBox.Value, app.TurCokluDelik15CheckBox.Value, app.TurCokluDelik16CheckBox.Value, app.TurCokluDelik17CheckBox.Value, app.TurCokluDelik23CheckBox.Value, app.TurCokluDelik24CheckBox.Value, app.TurCokluDelik25CheckBox.Value, app.TurCokluDelik26CheckBox.Value, app.TurCokluDelik27CheckBox.Value, app.TurCokluDelik34CheckBox.Value, app.TurCokluDelik35CheckBox.Value, app.TurCokluDelik36CheckBox.Value, app.TurCokluDelik37CheckBox.Value, app.TurCokluDelik45CheckBox.Value, app.TurCokluDelik46CheckBox.Value, app.TurCokluDelik47CheckBox.Value, app.TurCokluDelik56CheckBox.Value, app.TurCokluDelik57CheckBox.Value, app.TurCokluDelik67CheckBox.Value};                   
                        cokluCheckboxNameArray = {app.TurCokluDelik12CheckBox.Text, app.TurCokluDelik13CheckBox.Text, app.TurCokluDelik14CheckBox.Text, app.TurCokluDelik15CheckBox.Text, app.TurCokluDelik16CheckBox.Text, app.TurCokluDelik17CheckBox.Text, app.TurCokluDelik23CheckBox.Text, app.TurCokluDelik24CheckBox.Text, app.TurCokluDelik25CheckBox.Text, app.TurCokluDelik26CheckBox.Text, app.TurCokluDelik27CheckBox.Text, app.TurCokluDelik34CheckBox.Text, app.TurCokluDelik35CheckBox.Text, app.TurCokluDelik36CheckBox.Text, app.TurCokluDelik37CheckBox.Text, app.TurCokluDelik45CheckBox.Text, app.TurCokluDelik46CheckBox.Text, app.TurCokluDelik47CheckBox.Text, app.TurCokluDelik56CheckBox.Text, app.TurCokluDelik57CheckBox.Text, app.TurCokluDelik67CheckBox.Text};                   


                        if strcmp(app.TurBogazPanel.SelectedObject.Text, 'Boğaza Bağlı')
                            bogaz = ' (Boğaza Bağlı)';
                        end


                        for i = 1:21
                            if(strcmp(string(cokluCheckboxArray(i)), 'true'))
                                app.ikiliDelikler(length(app.ikiliDelikler)+1) = append('hole ', cokluCheckboxNameArray(i));
                            end
                        end
                        
                        for i = 1:7
                            if(strcmp(string(checkboxArray(i)), 'true'))
                                app.delikler(length(app.delikler)+1) = append('hole ', string(i));
                            end
                        end
                        
            
                        [~, search] = xlsread('matlabApp.xlsx', 'Sayfa1', 'A:A');
                        [~, tarih] = xlsread('matlabApp.xlsx', 'Sayfa1', 'C:C');
                        [~, saat] = xlsread('matlabApp.xlsx', 'Sayfa1', 'B:B');
                        
                        delikSecenekleri = cat(2,app.delikler, app.ikiliDelikler);
                        if(app.oncekiGrafikCheckBox.Value)
                            resettingGraph(app);
                        end
                        
                        identity = '';
                        
                        for h = 1:length(delikSecenekleri)
                        
                            satir = 0;
                            
                            
                            for i = 1:length(search)
                                if(h>length(app.delikler))
                                    if(~isempty(app.ikiliDelikler))
                                        if strcmp(search{i}, append(app.TurLPMPanel.SelectedObject.Text, ' - ', delikSecenekleri(h), ' - ',  'diameter ', string(dim(1)), bogaz))
                                            satir = i;
                                            identity = search{i};
                                        break
                                        end
                                    end
                                else
                                    if(~isempty(app.delikler))
                                        if strcmp(search{i}, append(app.TurLPMPanel.SelectedObject.Text, ' - ', delikSecenekleri(h), ' - ',  'diameter ', string(dim(1)), bogaz))
                                            satir = i;
                                            identity = search{i};
                                        break
                                        end
                                    end
                                end
                            end
                                           
                            if isfile('Deney_Verileri.xlsx')
                                if ~ismember(strrep(strrep(strrep(identity,' ',''), 'diameter', 'd'), 'hole', 'h'), sheetnames('Deney_Verileri.xlsx'))
                                    variables(app, string(tarih(satir))); 
                                    rowsValue(app, string(saat(satir)));
    
                                end
                            else
                                variables(app, string(tarih(satir))); 
                                rowsValue(app, string(saat(satir)));
                            end
                            
                            initializingGraph(app, string(tarih(satir)), app.startOfFilling, app.finishOfFilling, identity);  

                            if isfile('Deney_Verileri.xlsx')
                                if ~ismember(strrep(strrep(strrep(identity,' ',''), 'diameter', 'd'), 'hole', 'h'), sheetnames('Deney_Verileri.xlsx'))
                                    for i=1:6
                                        saveAsExcel(app, i, app.rows{i}, identity);
                                    end
                                end
                            else
                                for i=1:6
                                    saveAsExcel(app, i, app.rows{i}, identity);
                                end
                            end
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            %to plot pressure graph
                            
                            plottingBasinc1(app, append(search(satir), ' - '));                
                            plottingBasinc2(app, append(search(satir), ' - '));                
                            plottingDebi(app, append(search(satir), ' - '));                   
                            graphLabels(app);
                            
        
                        end
                    case 'Excel Satır Aralığını Belirleyerek'
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %to plot pressure graph
                        if(app.oncekiGrafikCheckBox.Value)
                            resettingGraph(app);
                        end

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %to plot graph
                        resettingGlobalVariables(app);
                        initializingGraph(app, app.EditFieldDosyaIsmi.Value, app.EditFieldBaslangic.Value, app.EditFieldBitis.Value, '');           
                        plottingBasinc1(app, '');           
                        plottingBasinc2(app, '');          
                        plottingDebi(app, '');      
                        graphLabels(app);
                    otherwise
                        if(app.oncekiGrafikCheckBox.Value)
                            resettingGraph(app);
                        end
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %preparing variables and data before plotting               
                        resettingGlobalVariables(app);
                        variables(app, app.EditFieldTarih.Value);      
                        rowsValue(app, app.EditFieldSaat.Value);
                       
                        
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        %to plot graph
                        initializingGraph(app, app.EditFieldTarih.Value, app.startOfFilling, app.finishOfFilling, '');  
                        plottingBasinc1(app, '');                
                        plottingBasinc2(app, '');                
                        plottingDebi(app, '');                   
                        graphLabels(app);
                end
                
                    
            else
                 warndlg('Lütfen En Az Bir Veri Seçiniz.','Veri Seçili Değil');
            end
        end

        % Button pushed function: VeriyiGsterButton
        function VeriyiGsterButtonPushed(app, event)
            arrayhold = jsondecode(app.dataset(find(strcmp(app.legendValue, app.VeriSetiDropDown.Value))));
            datatip(app.plots(find(strcmp(app.legendValue, app.VeriSetiDropDown.Value))+1) , app.SaniyeEditField.Value, arrayhold(round(20*app.SaniyeEditField.Value)));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DeneyZamannGirerekTab = uitab(app.TabGroup);
            app.DeneyZamannGirerekTab.Title = 'Deney Zamanını Girerek';
            app.DeneyTarihiLabel = uilabel(app.DeneyZamannGirerekTab);
            app.DeneyTarihiLabel.Text = 'Deney Tarihi';
            app.DeneySaatiLabel = uilabel(app.DeneyZamannGirerekTab);
            app.DeneySaatiLabel.Text = 'Deney Saati';
            app.EditFieldTarih = uieditfield(app.DeneyZamannGirerekTab, 'text');
            app.EditFieldSaat = uieditfield(app.DeneyZamannGirerekTab, 'text');
            app.DeneyTrSeerekTab = uitab(app.TabGroup);
            app.DeneyTrSeerekTab.Title = 'Deney Türü Seçerek';
            app.TurLPMPanel = uibuttongroup(app.DeneyTrSeerekTab);
            app.TurLPMPanel.Title = 'LPM Seçiniz';
            app.TurLPM40Radio = uiradiobutton(app.TurLPMPanel);
            app.TurLPM40Radio.Text = '40 lpm';
            app.TurLPM40Radio.Value = true;
            app.TurLPM80Radio = uiradiobutton(app.TurLPMPanel);
            app.TurLPM80Radio.Text = '80 lpm';
            app.TurLPM120Radio = uiradiobutton(app.TurLPMPanel);
            app.TurLPM120Radio.Text = '120 lpm';
            app.TurCapPanel = uibuttongroup(app.DeneyTrSeerekTab);
            app.TurCapPanel.Title = 'Çap Seçiniz';
            app.TurCap6mmRadio = uiradiobutton(app.TurCapPanel);
            app.TurCap6mmRadio.Text = '6 mm';
            app.TurCap6mmRadio.Value = true;
            app.TurCap8mmRadio = uiradiobutton(app.TurCapPanel);
            app.TurCap8mmRadio.Text = '8 mm';
            app.TurCap10mmRadio = uiradiobutton(app.TurCapPanel);
            app.TurCap10mmRadio.Text = '10 mm';
            app.TurCap12mmRadio = uiradiobutton(app.TurCapPanel);
            app.TurCap12mmRadio.Text = '12 mm';
            app.TurDelikPanel = uipanel(app.DeneyTrSeerekTab);
            app.TurDelikPanel.Title = 'Delik Seçiniz';
            app.TurDelik1CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik1CheckBox.Text = '1. Delik';
            app.TurDelik2CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik2CheckBox.Text = '2. Delik';
            app.TurDelik4CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik4CheckBox.Text = '4. Delik';
            app.TurDelik3CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik3CheckBox.Text = '3. Delik';
            app.TurDelik7CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik7CheckBox.Text = '7.  Delik';
            app.TurDelik6CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik6CheckBox.Text = '6. Delik';
            app.TurDelik5CheckBox = uicheckbox(app.TurDelikPanel);
            app.TurDelik5CheckBox.Text = '5. Delik';
            app.TurIkiliDelikPanel = uipanel(app.DeneyTrSeerekTab);
            app.TurIkiliDelikPanel.Title = 'İkili Delik Seçiniz';
            app.TurCokluDelik12CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik12CheckBox.Text = '1 + 2';
            app.TurCokluDelik13CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik13CheckBox.Text = '1 + 3';
            app.TurCokluDelik15CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik15CheckBox.Text = '1 + 5';
            app.TurCokluDelik14CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik14CheckBox.Text = '1 + 4';
            app.TurCokluDelik17CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik17CheckBox.Text = '1 + 7';
            app.TurCokluDelik16CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik16CheckBox.Text = '1 + 6';
            app.TurCokluDelik24CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik24CheckBox.Text = '2 + 4';
            app.TurCokluDelik23CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik23CheckBox.Text = '2 + 3';
            app.TurCokluDelik34CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik34CheckBox.Text = '3 + 4';
            app.TurCokluDelik27CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik27CheckBox.Text = '2 + 7';
            app.TurCokluDelik26CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik26CheckBox.Text = '2 + 6';
            app.TurCokluDelik25CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik25CheckBox.Text = '2 + 5';
            app.TurCokluDelik45CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik45CheckBox.Text = '4 + 5';
            app.TurCokluDelik37CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik37CheckBox.Text = '3 + 7';
            app.TurCokluDelik36CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik36CheckBox.Text = '3 + 6';
            app.TurCokluDelik35CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik35CheckBox.Text = '3 + 5';
            app.TurCokluDelik57CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik57CheckBox.Text = '5 + 7';
            app.TurCokluDelik56CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik56CheckBox.Text = '5 + 6';
            app.TurCokluDelik47CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik47CheckBox.Text = '4 + 7';
            app.TurCokluDelik46CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik46CheckBox.Text = '4 + 6';
            app.TurCokluDelik67CheckBox = uicheckbox(app.TurIkiliDelikPanel);
            app.TurCokluDelik67CheckBox.Text = '6 + 7';
            app.TurUcluDelikPanel = uipanel(app.DeneyTrSeerekTab);
            app.TurUcluDelikPanel.Title = 'Üçlü Delik Seçiniz';
            app.TurCokluDelik34CheckBox_2 = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik34CheckBox_2.Text = '3 + 4 + 5';
            app.TurCokluDelik27CheckBox_2 = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik27CheckBox_2.Text = '2 + 3 + 7';
            app.TurCokluDelik26CheckBox_2 = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik26CheckBox_2.Text = '2 + 6 + 7';
            app.TurCokluDelik25CheckBox_2 = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik25CheckBox_2.Text = '2 + 5 + 7';
            app.TurCokluDelik25CheckBox_3 = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik25CheckBox_3.Text = '2 + 5 + 7';
            app.TurCokluDelik26CheckBox_3 = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik26CheckBox_3.Text = '2 + 6 + 7';
            app.TurCokluDelik27CheckBox_3 = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik27CheckBox_3.Text = '2 + 3 + 7';
            app.TurCokluDelik34CheckBox_3 = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik34CheckBox_3.Text = '3 + 4 + 5';
            app.TurCokluDelik25CheckBox_4 = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik25CheckBox_4.Text = '2 + 5 + 7';
            app.TurCokluDelik26CheckBox_4 = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik26CheckBox_4.Text = '2 + 6 + 7';
            app.TurCokluDelik27CheckBox_4 = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik27CheckBox_4.Text = '2 + 3 + 7';
            app.TurCokluDelik34CheckBox_4 = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik34CheckBox_4.Text = '3 + 4 + 5';
            app.TurCokluDelik345CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik345CheckBox.Text = '3 + 4 + 5';
            app.TurCokluDelik237CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik237CheckBox.Text = '2 + 3 + 7';
            app.TurCokluDelik267CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik267CheckBox.Text = '2 + 6 + 7';
            app.TurCokluDelik257CheckBox = uicheckbox(app.TurUcluDelikPanel);
            app.TurCokluDelik257CheckBox.Text = '2 + 5 + 7';
            app.ExcelSatrAralnBelirleyerekTab = uitab(app.TabGroup);
            app.ExcelSatrAralnBelirleyerekTab.Title = 'Excel Satır Aralığını Belirleyerek';
            app.ExcelSatrBalangcLabel = uilabel(app.ExcelSatrAralnBelirleyerekTab);
            app.ExcelSatrBalangcLabel.Text = 'Excel Satır Başlangıcı';
            app.ExcelSatrSonuLabel = uilabel(app.ExcelSatrAralnBelirleyerekTab);
            app.ExcelSatrSonuLabel.Text = 'Excel Satır Sonu';
            app.EditFieldBitis = uieditfield(app.ExcelSatrAralnBelirleyerekTab, 'numeric');
            app.EditFieldBaslangic = uieditfield(app.ExcelSatrAralnBelirleyerekTab, 'numeric');
            app.EditFieldDosyaIsmi = uieditfield(app.ExcelSatrAralnBelirleyerekTab, 'text');
            app.ExcelDosyasnnismiLabel = uilabel(app.ExcelSatrAralnBelirleyerekTab);
            app.ExcelDosyasnnismiLabel.Text = 'Excel Dosyasının ismi';
            app.xlsxLabel = uilabel(app.ExcelSatrAralnBelirleyerekTab);
            app.xlsxLabel.Text = '.xlsx';
            app.oncekiGrafikCheckBox = uicheckbox(app.UIFigure);
            app.oncekiGrafikCheckBox.Text = 'Önceki grafiği sil';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Grafiği Göster';
            app.VeriPanel = uipanel(app.UIFigure);
            app.VeriPanel.Title = 'Verileri Seçiniz';
            app.VeriDebiCheckBox = uicheckbox(app.VeriPanel);
            app.VeriDebiCheckBox.Text = 'Debi';
            app.VeriBasinc2CheckBox = uicheckbox(app.VeriPanel);
            app.VeriBasinc2CheckBox.Text = 'Basınç 2';
            app.VeriBasinc1CheckBox = uicheckbox(app.VeriPanel);
            app.VeriBasinc1CheckBox.Text = 'Basınç 1';
            app.DeerGstericiPanel = uipanel(app.UIFigure);
            app.DeerGstericiPanel.Title = 'Değer Gösterici';
            app.VeriSetiDropDown = uidropdown(app.DeerGstericiPanel);
            app.SaniyeEditFieldLabel = uilabel(app.DeerGstericiPanel);
            app.SaniyeEditFieldLabel.Text = 'Saniye:';
            app.SaniyeEditField = uieditfield(app.DeerGstericiPanel, 'numeric');
            app.VeriyiGsterButton = uibutton(app.DeerGstericiPanel, 'push');
            app.VeriyiGsterButton.ButtonPushedFcn = createCallbackFcn(app, @VeriyiGsterButtonPushed, true);
            app.VeriyiGsterButton.Text = 'Veriyi Göster';
            app.ListBox = uilistbox(app.UIFigure);
            app.StandartSapmalarLabel = uilabel(app.UIFigure);
            app.StandartSapmalarLabel.Text = 'Standart Sapmalar';
            app.TurBogazPanel = uibuttongroup(app.UIFigure);
            app.TurBogazPanel.Title = 'Boğaza Bağlı mı?';
            app.BogazliRadio = uiradiobutton(app.TurBogazPanel);
            app.BogazliRadio.Text = 'Boğaza Bağlı';
            app.BogazliRadio.Value = true;
            app.BogazsizRadio = uiradiobutton(app.TurBogazPanel);
            app.BogazsizRadio.Text = 'Boğaza Bağlı Değil';
            app.BasncagrekalibreetCheckBox = uicheckbox(app.UIFigure);
            app.BasncagrekalibreetCheckBox.Text = 'Basınca göre kalibre et';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tankApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end